classdef ServoPositionierung < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TiltEditField       matlab.ui.control.NumericEditField
        TiltEditFieldLabel  matlab.ui.control.Label
        TiltSlider          matlab.ui.control.Slider
        TiltSliderLabel     matlab.ui.control.Label
        SpinSlider          matlab.ui.control.Slider
        SpinSliderLabel     matlab.ui.control.Label
        SpinEditField       matlab.ui.control.NumericEditField
        SpinEditFieldLabel  matlab.ui.control.Label
        StartCamButton      matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam % Webcam
        ardu % Arduinoverbindung
        spin % Servoobjekt für Spin
        tilt %Servoobjekt
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Objekte anlegen
            app.cam = webcam(2);
            app.ardu = arduino('COM6', 'Nano33IoT', 'Libraries', 'Servo');
            app.spin = servo(app.ardu, 'D2');
            app.tilt = servo(app.ardu, 'D3');
            %Startwerte setzen
            writePosition(app.spin, 0.5);
            app.SpinEditField.Value = 0.5;
            app.SpinSlider.Value = 0.5;
            writePosition(app.tilt, 0.35);
            app.TiltEditField.Value = 0.35;
            app.TiltSlider.Value = 0.35;
        end

        % Button pushed function: StartCamButton
        function StartCamButtonPushed(app, event)
            frame = snapshot(app.cam);
            im = image(app.UIAxes, zeros(size(frame), 'uint8'));
            axis(app.UIAxes, 'image');
            preview(app.cam, im);
        end

        % Callback function
        function InitButtonPushed(app, event)
       
        end

        % Callback function
        function DriveButtonPushed(app, event)

        end

        % Value changed function: SpinSlider
        function SpinSliderValueChanged(app, event)
            value = app.SpinSlider.Value;
            writePosition(app.spin, value);
            app.SpinEditField.Value = value;
        end

        % Value changed function: TiltSlider
        function TiltSliderValueChanged(app, event)
            value = app.TiltSlider.Value;
            writePosition(app.tilt, value);
            app.TiltEditField.Value = value;
        end

        % Value changed function: SpinEditField
        function SpinEditFieldValueChanged(app, event)
            value = app.SpinEditField.Value;
            writePosition(app.spin, value);
            app.SpinSlider.Value = value;
        end

        % Value changed function: TiltEditField
        function TiltEditFieldValueChanged(app, event)
            value = app.TiltEditField.Value;
            writePosition(app.tilt, value);
            app.TiltSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartCamButton = uibutton(app.UIFigure, 'push');
            app.StartCamButton.ButtonPushedFcn = createCallbackFcn(app, @StartCamButtonPushed, true);
            app.StartCamButton.Text = 'Start Cam';
            app.SpinEditFieldLabel = uilabel(app.UIFigure);
            app.SpinEditFieldLabel.Text = 'Spin';
            app.SpinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpinEditField.ValueChangedFcn = createCallbackFcn(app, @SpinEditFieldValueChanged, true);
            app.SpinSliderLabel = uilabel(app.UIFigure);
            app.SpinSliderLabel.Text = 'Spin';
            app.SpinSlider = uislider(app.UIFigure);
            app.SpinSlider.Limits = [0 1];
            app.SpinSlider.ValueChangedFcn = createCallbackFcn(app, @SpinSliderValueChanged, true);
            app.TiltSliderLabel = uilabel(app.UIFigure);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.UIFigure);
            app.TiltSlider.Limits = [0 1];
            app.TiltSlider.ValueChangedFcn = createCallbackFcn(app, @TiltSliderValueChanged, true);
            app.TiltEditFieldLabel = uilabel(app.UIFigure);
            app.TiltEditFieldLabel.Text = 'Tilt';
            app.TiltEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiltEditField.ValueChangedFcn = createCallbackFcn(app, @TiltEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ServoPositionierung

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end